classdef IHMRoboDidatico_V0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RobotHMIUIFigure              matlab.ui.Figure
        RunMenu                       matlab.ui.container.Menu
        LoadTrajectoryMenu            matlab.ui.container.Menu
        MoveRobotintoPositionMenu     matlab.ui.container.Menu
        ProgramMenu                   matlab.ui.container.Menu
        SimpleTrajectoryMenu          matlab.ui.container.Menu
        MoveMenu_2                    matlab.ui.container.Menu
        WaypointMenu                  matlab.ui.container.Menu
        SetMenu                       matlab.ui.container.Menu
        WaitMenu                      matlab.ui.container.Menu
        PopupMenu                     matlab.ui.container.Menu
        HaltMenu                      matlab.ui.container.Menu
        AdvancedTrajectoryMenu        matlab.ui.container.Menu
        NewScriptMenu                 matlab.ui.container.Menu
        LoadScriptMenu                matlab.ui.container.Menu
        TemplatesMenu                 matlab.ui.container.Menu
        SeekMenu                      matlab.ui.container.Menu
        ForceMenu                     matlab.ui.container.Menu
        PalletizingMenu               matlab.ui.container.Menu
        ConveyorTrackingMenu          matlab.ui.container.Menu
        RemoteToolMenu                matlab.ui.container.Menu
        RemoteToolCPMoveMenu          matlab.ui.container.Menu
        InstallationMenu              matlab.ui.container.Menu
        GeneralMenu                   matlab.ui.container.Menu
        ToolCPMenu                    matlab.ui.container.Menu
        MountingMenu                  matlab.ui.container.Menu
        IOSetupMenu                   matlab.ui.container.Menu
        StartupMenu                   matlab.ui.container.Menu
        HomeMenu                      matlab.ui.container.Menu
        SafetyMenu                    matlab.ui.container.Menu
        RobotLimitsMenu               matlab.ui.container.Menu
        JointLimitsMenu               matlab.ui.container.Menu
        PlanesMenu                    matlab.ui.container.Menu
        ToolPositionMenu              matlab.ui.container.Menu
        ToolDirectionMenu             matlab.ui.container.Menu
        IOMenu_2                      matlab.ui.container.Menu
        HardwareMenu                  matlab.ui.container.Menu
        ThreePositionMenu             matlab.ui.container.Menu
        SafeHomeMenu                  matlab.ui.container.Menu
        FeaturesMenu                  matlab.ui.container.Menu
        PointMenu                     matlab.ui.container.Menu
        LineMenu                      matlab.ui.container.Menu
        PlaneMenu                     matlab.ui.container.Menu
        FieldbusMenu                  matlab.ui.container.Menu
        MoveMenu                      matlab.ui.container.Menu
        IOMenu                        matlab.ui.container.Menu
        LogMenu                       matlab.ui.container.Menu
        ReadingsMenu                  matlab.ui.container.Menu
        JointLoadMenu                 matlab.ui.container.Menu
        DateLogMenu                   matlab.ui.container.Menu
        PlotsMenu                     matlab.ui.container.Menu
        SettingsMenu                  matlab.ui.container.Menu
        PreferencesMenu               matlab.ui.container.Menu
        PasswordMenu                  matlab.ui.container.Menu
        SystemMenu                    matlab.ui.container.Menu
        RobotRegistrationMenu         matlab.ui.container.Menu
        Joint1DegreeAngle             matlab.ui.control.NumericEditField
        Joint2DegreeAngle             matlab.ui.control.NumericEditField
        Joint5DegreeAngle             matlab.ui.control.NumericEditField
        Joint4DegreeAngle             matlab.ui.control.NumericEditField
        Joint3DegreeAngle             matlab.ui.control.NumericEditField
        Joint1Label                   matlab.ui.control.Label
        Joint2Label                   matlab.ui.control.Label
        Joint3Label                   matlab.ui.control.Label
        Joint4Label                   matlab.ui.control.Label
        Joint5Label                   matlab.ui.control.Label
        HomeButton                    matlab.ui.control.Button
        SliderJoint1                  matlab.ui.control.Slider
        SliderJoint2                  matlab.ui.control.Slider
        SliderJoint3                  matlab.ui.control.Slider
        SliderJoint4                  matlab.ui.control.Slider
        SliderJoint5                  matlab.ui.control.Slider
        T05Table                      matlab.ui.control.Table
        RightArrowSlider1             matlab.ui.control.Button
        RightArrowSlider2             matlab.ui.control.Button
        RightArrowSlider3             matlab.ui.control.Button
        RightArrowSlider4             matlab.ui.control.Button
        RightArrowSlider5             matlab.ui.control.Button
        LeftArrowSlider1              matlab.ui.control.Button
        LeftArrowSlider2              matlab.ui.control.Button
        LeftArrowSlider3              matlab.ui.control.Button
        LeftArrowSlider4              matlab.ui.control.Button
        LeftArrowSlider5              matlab.ui.control.Button
        ControlButton                 matlab.ui.control.Button
        JointPositionLabel            matlab.ui.control.Label
        ToolPositionLabel             matlab.ui.control.Label
        XLabel                        matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        mmLabel                       matlab.ui.control.Label
        mmLabel_2                     matlab.ui.control.Label
        mmLabel_3                     matlab.ui.control.Label
        YLabel                        matlab.ui.control.Label
        ZLabel                        matlab.ui.control.Label
        Label_7                       matlab.ui.control.Label
        TransformationMatrixT05Label  matlab.ui.control.Label
        PlanDropDownLabel             matlab.ui.control.Label
        PlanDropDown                  matlab.ui.control.DropDown
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        AnimationSwitchLabel          matlab.ui.control.Label
        AnimationSwitch               matlab.ui.control.Switch
        InverseKinematicButton        matlab.ui.control.Button
        positionZ                     matlab.ui.control.NumericEditField
        positionY                     matlab.ui.control.NumericEditField
        positionX                     matlab.ui.control.NumericEditField
        alpha                         matlab.ui.control.NumericEditField
        beta                          matlab.ui.control.NumericEditField
        Label_8                       matlab.ui.control.Label
        Label_9                       matlab.ui.control.Label
        NotPossibleLabel              matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Save actual thetas
        theta1 = 0;
        theta2 = 0;
        theta3 = 0;
        theta4 = 0;
        theta5 = 0;
        
        % Save last thetas
        oldTheta1 = 0;
        oldTheta2 = 0;
        oldTheta3 = 0;
        oldTheta4 = 0;
        oldTheta5 = 0;
        
        timeBetweenWaypoints = 10;
        links;
        
        % Windows
        logPlotWindow; % Log -> Plot
        runLoadTrajectoryWindow % Run -> Load Trajectory
        jointsLimitsWindow % Installation -> Safety -> Joint Limits
    end
    
    properties (Access = public)
        % Control Variables
        th_realizado, th_desejado;
        dth_realizado, dth_desejado;
        ddth_realizado, ddth_desejado;
        esforco_controle;
        theta_erro;
        tout;
        G;
        V;
        tal_res, tal_motor;
        
        % Trajectory Variables
        trajectory;
        x_realizado, y_realizado, z_realizado;
        x_desejado, y_desejado, z_desejado;
        
        JointsLimits;
    end
    
    methods (Access = private)
        
        function updateTheta(app)
            app.theta1 = app.Joint1DegreeAngle.Value;
            app.theta2 = app.Joint2DegreeAngle.Value;
            app.theta3 = app.Joint3DegreeAngle.Value;
            app.theta4 = app.Joint4DegreeAngle.Value;
            app.theta5 = app.Joint5DegreeAngle.Value;
            
            updateTrajectory(app);
            [app.T05Table.Data] = cin_direta([app.theta1, ...
                                              app.theta2, ...
                                              app.theta3, ...
                                              app.theta4, ...
                                              app.theta5]);
        end
        
        function patchDidaticRobot(app, links_plot)
            patch(app.UIAxes,'Faces',links_plot.s_BASE.F,'Vertices',links_plot.s_BASE.V(:,1:3),'EdgeColor','none','FaceColor',[4, 64, 29]./255);
            patch(app.UIAxes,'Faces',links_plot.s_1M2A.F,'Vertices',links_plot.s_1M2A.V(:,1:3),'EdgeColor','none','FaceColor',[9, 165, 77]./255);
            patch(app.UIAxes,'Faces',links_plot.s_1M1B.F,'Vertices',links_plot.s_1M1B.V(:,1:3),'EdgeColor','none','FaceColor',[13, 242, 112]./255);
            patch(app.UIAxes,'Faces',links_plot.s_2M1D.F,'Vertices',links_plot.s_2M1D.V(:,1:3),'EdgeColor','none','FaceColor',[115, 247, 172]./255);
            patch(app.UIAxes,'Faces',links_plot.s_motor_2a.F,'Vertices',links_plot.s_motor_2a.V(:,1:3),'EdgeColor','none','FaceColor',[211,211,211]./255);
            patch(app.UIAxes,'Faces',links_plot.s_motor_2b.F,'Vertices',links_plot.s_motor_2b.V(:,1:3),'EdgeColor','none','FaceColor',[211,211,211]./255);
            patch(app.UIAxes,'Faces',links_plot.s_2M2HA.F,'Vertices',links_plot.s_2M2HA.V(:,1:3),'EdgeColor','none','FaceColor',[183,30,31]./255);
            patch(app.UIAxes,'Faces',links_plot.s_2M2MA.F,'Vertices',links_plot.s_2M2MA.V(:,1:3),'EdgeColor','none','FaceColor',[183,30,31]./255);
            patch(app.UIAxes,'Faces',links_plot.s_3M1D.F,'Vertices',links_plot.s_3M1D.V(:,1:3),'EdgeColor','none','FaceColor',[232,102,102]./255);
            patch(app.UIAxes,'Faces',links_plot.s_motor_3.F,'Vertices',links_plot.s_motor_3.V(:,1:3),'EdgeColor','none','FaceColor',[211,211,211]./255);
            patch(app.UIAxes,'Faces',links_plot.s_3M2C.F,'Vertices',links_plot.s_3M2C.V(:,1:3),'EdgeColor','none','FaceColor',[11,91,140]./255);
            patch(app.UIAxes,'Faces',links_plot.s_3M2CC.F,'Vertices',links_plot.s_3M2CC.V(:,1:3),'EdgeColor','none','FaceColor',[11,91,140]./255);
            patch(app.UIAxes,'Faces',links_plot.s_4M1D.F,'Vertices',links_plot.s_4M1D.V(:,1:3),'EdgeColor','none','FaceColor',[78,187,255]./255);
            patch(app.UIAxes,'Faces',links_plot.s_motor_4.F,'Vertices',links_plot.s_motor_4.V(:,1:3),'EdgeColor','none','FaceColor',[211,211,211]./255);
            patch(app.UIAxes,'Faces',links_plot.s_4M2B.F,'Vertices',links_plot.s_4M2B.V(:,1:3),'EdgeColor','none','FaceColor',[155,209,242]./255);
            patch(app.UIAxes,'Faces',links_plot.s_4M2CB.F,'Vertices',links_plot.s_4M2CB.V(:,1:3),'EdgeColor','none','FaceColor',[155,209,242]./255);
%             patch(app.UIAxes,'Faces',links_plot.s_garra.F,'Vertices',links_plot.s_garra.V(:,1:3),'EdgeColor','none','FaceColor',[244,245,238]./255);
        end
        
        function animateRobot(app, finalPlotIndex, nbSteps)
            
            % Arrays to save realized tool position path
            app.x_realizado = [];
            app.y_realizado = [];
            app.z_realizado = [];
            
            % Arrays to save desired tool position path
            app.x_desejado = [];
            app.y_desejado = [];
            app.z_desejado = [];
            
            for i = 2:(finalPlotIndex-2)/nbSteps:finalPlotIndex
              [links_plot, app.x_realizado, app.y_realizado, app.z_realizado, ...
               app.x_desejado, app.y_desejado, app.z_desejado] = plotRobot3D(app.links, app.th_realizado(round(i),1), app.th_realizado(round(i),2), app.th_realizado(round(i),3), ...
                                                          app.th_realizado(round(i),4), app.th_realizado(round(i),5), ...
                                                          app.th_desejado(round(i),1), app.th_desejado(round(i),2), app.th_desejado(round(i),3), ...
                                                          app.th_desejado(round(i),4), app.th_desejado(round(i),5), ...
                                                          app.x_realizado, app.y_realizado, app.z_realizado, ...
                                                          app.x_desejado, app.y_desejado, app.z_desejado, "5R");
                
                % Clear UIAxes                                               
                cla(app.UIAxes) 
                
                % Plot robotic arm
                patchDidaticRobot(app, links_plot)
                
%                 light(app.UIAxes);
                
                % Plot realized trajectory
                hold(app.UIAxes, 'on')
                plot3(app.UIAxes, app.x_realizado, app.y_realizado, app.z_realizado, 'r-');
               
                % Plot desired trajectory
                hold(app.UIAxes, 'on')
                plot3(app.UIAxes, app.x_desejado, app.y_desejado, app.z_desejado, 'b-');
                drawnow
                
                app.SliderJoint1.Value = round(app.th_realizado(round(i),1), 2);
                app.SliderJoint2.Value = round(app.th_realizado(round(i),2), 2);
                app.SliderJoint3.Value = round(app.th_realizado(round(i),3), 2);
                app.SliderJoint4.Value = round(app.th_realizado(round(i),4), 2);
                app.SliderJoint5.Value = round(app.th_realizado(round(i),5), 2);
                app.Joint1DegreeAngle.Value = round(app.th_realizado(round(i),1), 2);
                app.Joint2DegreeAngle.Value = round(app.th_realizado(round(i),2), 2);
                app.Joint3DegreeAngle.Value = round(app.th_realizado(round(i),3), 2);
                app.Joint4DegreeAngle.Value = round(app.th_realizado(round(i),4), 2);
                app.Joint5DegreeAngle.Value = round(app.th_realizado(round(i),5), 2);
                
                updateTheta(app);
                
                app.positionX.Value = app.x_realizado(end);
                app.positionY.Value = app.y_realizado(end);
                app.positionZ.Value = app.z_realizado(end);
                app.alpha.Value = app.theta5;
                app.beta.Value = app.theta4;
            end
            
            light(app.UIAxes);
        end
        
        function plotRobotInitialPosition(app)            
            [links_plot, app.x_realizado, app.y_realizado, app.z_realizado, ...
               app.x_desejado, app.y_desejado, app.z_desejado] = plotRobot3D(app.links, 0,0,0,0,0, ...
                                                                            0,0,0,0,0, ...
                                                                            [], [], [], ...
                                                                            [], [], [], "5R");
            
            % Clear UIAxes
            cla(app.UIAxes)
            
            % Plot robotic arm
            patchDidaticRobot(app, links_plot)
            
%             drawnow
            updateTheta(app);
            light(app.UIAxes);
        end
        
        function updateTrajectory(app)
            app.trajectory = [[app.oldTheta1 app.theta1];
                              [app.oldTheta2 app.theta2];
                              [app.oldTheta3 app.theta3];
                              [app.oldTheta4 app.theta4];
                              [app.oldTheta5 app.theta5]];
        end
        
        function controlRobot(app)            
            addpath(strcat(fileparts(fileparts(pwd)),'\02. Trajetórias'))
            createTrajectory(app.trajectory, app.timeBetweenWaypoints);
            
%             assignin('base', 'SP_theta', [app.trajectory(1,:), ...
%                                           app.trajectory(2,:), ...
%                                           app.trajectory(3,:), ...
%                                           app.trajectory(4,:), ...
%                                           app.trajectory(5,:)]);
            
            addpath(strcat(fileparts(fileparts(pwd)),'\03. Controle'))
            [app.th_realizado, app.th_desejado, app.dth_realizado, app.dth_desejado, ...
             app.ddth_realizado, app.ddth_desejado, app.esforco_controle, ...
             app.theta_erro, app.G, app.V, app.tal_res, app.tal_motor, app.tout] = ...
             control_robotic_arm((size(app.trajectory, 2) - 1)*app.timeBetweenWaypoints + 1, app.trajectory);
            
            app.oldTheta1 = app.theta1;
            app.oldTheta2 = app.theta2;
            app.oldTheta3 = app.theta3;
            app.oldTheta4 = app.theta4;
            app.oldTheta5 = app.theta5;
            
            n = length(app.th_realizado);
            
            if app.AnimationSwitch.Value == 'On'
                animateRobot(app, n, 20)
            elseif app.AnimationSwitch.Value == 'Off'
                animateRobot(app, n, 3)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initializeRobotParameters();
            addpath(strcat(fileparts(fileparts(pwd)),'\05. Projeto Mecânico'))
            app.links = load('clean_linksdata.mat');
            
            xlabel(app.UIAxes, 'X [mm]')
            ylabel(app.UIAxes, 'Y [mm]')
            zlabel(app.UIAxes, 'Z [mm]')
            daspect(app.UIAxes, [1,1,1])
            light(app.UIAxes);
            view(app.UIAxes,45,25);
            grid(app.UIAxes,'on');
            xlim(app.UIAxes, [-650,650]),ylim(app.UIAxes, [-500,500]),zlim(app.UIAxes, [-300,600]); % 5R
            
            plotRobotInitialPosition(app);
        end

        % Value changing function: SliderJoint1
        function SliderJoint1ValueChanging(app, event)
            changingValue = event.Value;
            app.Joint1DegreeAngle.Value = round(changingValue);
        end

        % Value changing function: SliderJoint2
        function SliderJoint2ValueChanging(app, event)
            changingValue = event.Value;
            app.Joint2DegreeAngle.Value = round(changingValue);
        end

        % Value changing function: SliderJoint3
        function SliderJoint3ValueChanging(app, event)
            changingValue = event.Value;
            app.Joint3DegreeAngle.Value = round(changingValue);
        end

        % Value changing function: SliderJoint4
        function SliderJoint4ValueChanging(app, event)
            changingValue = event.Value;
            app.Joint4DegreeAngle.Value = round(changingValue);
        end

        % Value changing function: SliderJoint5
        function SliderJoint5ValueChanging(app, event)
            changingValue = event.Value;
            app.Joint5DegreeAngle.Value = round(changingValue);
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            % Set robot to home position            
            app.trajectory = [[app.theta1 0];
                              [app.theta2 0];
                              [app.theta3 0];
                              [app.theta4 0];
                              [app.theta5 0]];
            
            controlRobot(app);
        end

        % Value changed function: SliderJoint1
        function SliderJoint1ValueChanged(app, event)
            updateTheta(app);
%             app.SliderJoint1.Limits = [round(app.theta1)-180 round(app.theta1)+180];
        end

        % Value changed function: SliderJoint2
        function SliderJoint2ValueChanged(app, event)
            updateTheta(app);
        end

        % Value changed function: SliderJoint3
        function SliderJoint3ValueChanged(app, event)
            updateTheta(app);
        end

        % Value changed function: SliderJoint4
        function SliderJoint4ValueChanged(app, event)
            updateTheta(app);
        end

        % Value changed function: SliderJoint5
        function SliderJoint5ValueChanged(app, event)
            updateTheta(app);
        end

        % Button pushed function: RightArrowSlider1
        function RightArrowSlider1ButtonPushed(app, event)
            app.SliderJoint1.Value = app.SliderJoint1.Value + 1;
            app.Joint1DegreeAngle.Value = app.SliderJoint1.Value;
            updateTheta(app);
        end

        % Button pushed function: RightArrowSlider2
        function RightArrowSlider2ButtonPushed(app, event)
            app.SliderJoint2.Value = app.SliderJoint2.Value + 1;
            app.Joint2DegreeAngle.Value = app.SliderJoint2.Value;
            updateTheta(app);
        end

        % Button pushed function: RightArrowSlider3
        function RightArrowSlider3ButtonPushed(app, event)
            app.SliderJoint3.Value = app.SliderJoint3.Value + 1;
            app.Joint3DegreeAngle.Value = app.SliderJoint3.Value;
            updateTheta(app);
        end

        % Button pushed function: RightArrowSlider4
        function RightArrowSlider4ButtonPushed(app, event)
            app.SliderJoint4.Value = app.SliderJoint4.Value + 1;
            app.Joint4DegreeAngle.Value = app.SliderJoint4.Value;
            updateTheta(app);
        end

        % Button pushed function: RightArrowSlider5
        function RightArrowSlider5ButtonPushed(app, event)
            app.SliderJoint5.Value = app.SliderJoint5.Value + 1;
            app.Joint5DegreeAngle.Value = app.SliderJoint5.Value;
            updateTheta(app);
        end

        % Button pushed function: LeftArrowSlider1
        function LeftArrowSlider1ButtonPushed(app, event)
            app.SliderJoint1.Value = app.SliderJoint1.Value - 1;
            app.Joint1DegreeAngle.Value = app.SliderJoint1.Value;
            updateTheta(app);
        end

        % Button pushed function: LeftArrowSlider2
        function LeftArrowSlider2ButtonPushed(app, event)
            app.SliderJoint2.Value = app.SliderJoint2.Value - 1;
            app.Joint2DegreeAngle.Value = app.SliderJoint2.Value;
            updateTheta(app);
        end

        % Button pushed function: LeftArrowSlider3
        function LeftArrowSlider3ButtonPushed(app, event)
            app.SliderJoint3.Value = app.SliderJoint3.Value - 1;
            app.Joint3DegreeAngle.Value = app.SliderJoint3.Value;
            updateTheta(app);
        end

        % Button pushed function: LeftArrowSlider4
        function LeftArrowSlider4ButtonPushed(app, event)
            app.SliderJoint4.Value = app.SliderJoint4.Value - 1;
            app.Joint4DegreeAngle.Value = app.SliderJoint4.Value;
            updateTheta(app);
        end

        % Button pushed function: LeftArrowSlider5
        function LeftArrowSlider5ButtonPushed(app, event)
            app.SliderJoint5.Value = app.SliderJoint5.Value - 1;
            app.Joint5DegreeAngle.Value = app.SliderJoint5.Value;
            updateTheta(app);
        end

        % Value changed function: Joint1DegreeAngle
        function Joint1DegreeAngleValueChanged(app, event)
            app.SliderJoint1.Value = app.Joint1DegreeAngle.Value;
            updateTheta(app);
        end

        % Value changed function: Joint2DegreeAngle
        function Joint2DegreeAngleValueChanged(app, event)
            app.SliderJoint2.Value = app.Joint2DegreeAngle.Value;
            updateTheta(app);
        end

        % Value changed function: Joint3DegreeAngle
        function Joint3DegreeAngleValueChanged(app, event)
            app.SliderJoint3.Value = app.Joint3DegreeAngle.Value;
            updateTheta(app);
        end

        % Value changed function: Joint4DegreeAngle
        function Joint4DegreeAngleValueChanged(app, event)
            app.SliderJoint4.Value = app.Joint4DegreeAngle.Value;
            updateTheta(app);
        end

        % Value changed function: Joint5DegreeAngle
        function Joint5DegreeAngleValueChanged(app, event)
            app.SliderJoint5.Value = app.Joint5DegreeAngle.Value;
            updateTheta(app);
        end

        % Menu selected function: LoadTrajectoryMenu
        function LoadTrajectoryMenuSelected(app, event)
            % Open the run load trajectory window
            app.runLoadTrajectoryWindow = runLoadTrajectoryWindowApp(app);
        end

        % Value changed function: PlanDropDown
        function PlanDropDownValueChanged(app, event)
            option = app.PlanDropDown.Value;
            if strcmp(option, 'Isometric')
                view(app.UIAxes,45,25);
            elseif strcmp(option, 'XY')
                view(app.UIAxes,0,90);
            elseif strcmp(option, 'XZ')
                view(app.UIAxes,0,0);
            elseif strcmp(option, 'YZ')
                view(app.UIAxes,90,0);
%             elseif strcmp(option, 'YX')
%                 view(app.UIAxes,90,90);
%             elseif strcmp(option, 'ZX')
%                 view(app.UIAxes,0,-90);
%             elseif strcmp(option, 'ZY')
%                 view(app.UIAxes,90,0);
            else
                view(app.UIAxes,45,25);
            end
        end

        % Menu selected function: PlotsMenu
        function PlotsMenuSelected(app, event)
            % Disable Log Options menu while checkbox is open
%             app.LogMenu.Enable = 'off';
            
            % Open the log plot window
            app.logPlotWindow = logPlotWindowApp(app, app.th_realizado, app.th_desejado, ...
                                                 app.dth_realizado, app.dth_desejado, ...
                                                 app.ddth_realizado, app.ddth_desejado, ...
                                                 app.esforco_controle, app.theta_erro, app.tout);
        end

        % Button pushed function: InverseKinematicButton
        function InverseKinematicButtonPushed(app, event)
            addpath(strcat(fileparts(fileparts(pwd)),'\01. Modelagem\02. Cinemática Inversa 5 Juntas'))
            
            Theta_rad = cinematica_inversa(app.positionX.Value/1000, ...
                                           app.positionY.Value/1000, ...
                                           app.positionZ.Value/1000, ...
                                           deg2rad(app.alpha.Value), deg2rad(app.beta.Value), ...
                                           deg2rad([app.theta1, ...
                                                    app.theta2, ...
                                                    app.theta3-90, ...
                                                    app.theta4, ...
                                                    app.theta5]), ...
                                           [0.226, 0.250, 0.160 + 0.072, 0.075], ...
                                           [[app.SliderJoint1.Limits]; ...
                                            [app.SliderJoint2.Limits]; ...
                                            [app.SliderJoint3.Limits]; ...
                                            [app.SliderJoint4.Limits]; ...
                                            [app.SliderJoint5.Limits]]);
            
            if any(Theta_rad)
                Theta_degree = rad2deg(Theta_rad);
                
                offset = -90;
                
                app.trajectory = [[app.theta1 Theta_degree(1)];
                                  [app.theta2 Theta_degree(2)];
                                  [app.theta3 Theta_degree(3)-offset];
                                  [app.theta4 Theta_degree(4)];
                                  [app.theta5 Theta_degree(5)]];
                
                controlRobot(app);
            else
                app.NotPossibleLabel.Visible = true;
            end
        end

        % Button pushed function: ControlButton
        function ControlButtonPushed(app, event)
%             assignin('base', 'SP_theta', [app.trajectory(1,1), ...
%                                           app.trajectory(2,1), ...
%                                           app.trajectory(3,1), ...
%                                           app.trajectory(4,1), ...
%                                           app.trajectory(5,1)])
            controlRobot(app);
        end

        % Menu selected function: JointLimitsMenu
        function JointLimitsMenuSelected(app, event)
            % Open the joints limits window
            app.jointsLimitsWindow = JointsLimitsWindowApp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RobotHMIUIFigure = uifigure('Visible', 'off');
            app.RobotHMIUIFigure.Name = 'Didactic Robot HMI V0.0';
            app.RunMenu = uimenu(app.RobotHMIUIFigure);
            app.RunMenu.Text = 'Run';
            app.LoadTrajectoryMenu = uimenu(app.RunMenu);
            app.LoadTrajectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadTrajectoryMenuSelected, true);
            app.LoadTrajectoryMenu.Text = 'Load Trajectory';
            app.MoveRobotintoPositionMenu = uimenu(app.RunMenu);
            app.MoveRobotintoPositionMenu.Text = 'Move Robot into Position';
            app.ProgramMenu = uimenu(app.RobotHMIUIFigure);
            app.ProgramMenu.Text = 'Program';
            app.SimpleTrajectoryMenu = uimenu(app.ProgramMenu);
            app.SimpleTrajectoryMenu.Text = 'Simple Trajectory';
            app.MoveMenu_2 = uimenu(app.SimpleTrajectoryMenu);
            app.MoveMenu_2.Text = 'Move';
            app.WaypointMenu = uimenu(app.SimpleTrajectoryMenu);
            app.WaypointMenu.Text = 'Waypoint';
            app.SetMenu = uimenu(app.SimpleTrajectoryMenu);
            app.SetMenu.Text = 'Set';
            app.WaitMenu = uimenu(app.SimpleTrajectoryMenu);
            app.WaitMenu.Text = 'Wait';
            app.PopupMenu = uimenu(app.SimpleTrajectoryMenu);
            app.PopupMenu.Text = 'Popup';
            app.HaltMenu = uimenu(app.SimpleTrajectoryMenu);
            app.HaltMenu.Text = 'Halt';
            app.AdvancedTrajectoryMenu = uimenu(app.ProgramMenu);
            app.AdvancedTrajectoryMenu.Text = 'Advanced Trajectory';
            app.NewScriptMenu = uimenu(app.AdvancedTrajectoryMenu);
            app.NewScriptMenu.Text = 'New Script';
            app.LoadScriptMenu = uimenu(app.AdvancedTrajectoryMenu);
            app.LoadScriptMenu.Text = 'Load Script';
            app.TemplatesMenu = uimenu(app.ProgramMenu);
            app.TemplatesMenu.Text = 'Templates';
            app.SeekMenu = uimenu(app.TemplatesMenu);
            app.SeekMenu.Text = 'Seek';
            app.ForceMenu = uimenu(app.TemplatesMenu);
            app.ForceMenu.Text = 'Force';
            app.PalletizingMenu = uimenu(app.TemplatesMenu);
            app.PalletizingMenu.Text = 'Palletizing';
            app.ConveyorTrackingMenu = uimenu(app.TemplatesMenu);
            app.ConveyorTrackingMenu.Text = 'Conveyor Tracking';
            app.RemoteToolMenu = uimenu(app.ProgramMenu);
            app.RemoteToolMenu.Text = 'Remote Tool';
            app.RemoteToolCPMoveMenu = uimenu(app.RemoteToolMenu);
            app.RemoteToolCPMoveMenu.Text = 'Remote Tool CP Move';
            app.InstallationMenu = uimenu(app.RobotHMIUIFigure);
            app.InstallationMenu.Text = 'Installation';
            app.GeneralMenu = uimenu(app.InstallationMenu);
            app.GeneralMenu.Text = 'General';
            app.ToolCPMenu = uimenu(app.GeneralMenu);
            app.ToolCPMenu.Text = 'Tool CP';
            app.MountingMenu = uimenu(app.GeneralMenu);
            app.MountingMenu.Text = 'Mounting';
            app.IOSetupMenu = uimenu(app.GeneralMenu);
            app.IOSetupMenu.Text = 'I/O Setup';
            app.StartupMenu = uimenu(app.GeneralMenu);
            app.StartupMenu.Text = 'Startup';
            app.HomeMenu = uimenu(app.GeneralMenu);
            app.HomeMenu.Text = 'Home';
            app.SafetyMenu = uimenu(app.InstallationMenu);
            app.SafetyMenu.Text = 'Safety';
            app.RobotLimitsMenu = uimenu(app.SafetyMenu);
            app.RobotLimitsMenu.Text = 'Robot Limits';
            app.JointLimitsMenu = uimenu(app.SafetyMenu);
            app.JointLimitsMenu.MenuSelectedFcn = createCallbackFcn(app, @JointLimitsMenuSelected, true);
            app.JointLimitsMenu.Text = 'Joint Limits';
            app.PlanesMenu = uimenu(app.SafetyMenu);
            app.PlanesMenu.Text = 'Planes';
            app.ToolPositionMenu = uimenu(app.SafetyMenu);
            app.ToolPositionMenu.Text = 'Tool Position';
            app.ToolDirectionMenu = uimenu(app.SafetyMenu);
            app.ToolDirectionMenu.Text = 'Tool Direction';
            app.IOMenu_2 = uimenu(app.SafetyMenu);
            app.IOMenu_2.Text = 'I/O';
            app.HardwareMenu = uimenu(app.SafetyMenu);
            app.HardwareMenu.Text = 'Hardware';
            app.ThreePositionMenu = uimenu(app.SafetyMenu);
            app.ThreePositionMenu.Text = 'Three Position';
            app.SafeHomeMenu = uimenu(app.SafetyMenu);
            app.SafeHomeMenu.Text = 'Safe Home';
            app.FeaturesMenu = uimenu(app.InstallationMenu);
            app.FeaturesMenu.Text = 'Features';
            app.PointMenu = uimenu(app.FeaturesMenu);
            app.PointMenu.Text = 'Point';
            app.LineMenu = uimenu(app.FeaturesMenu);
            app.LineMenu.Text = 'Line';
            app.PlaneMenu = uimenu(app.FeaturesMenu);
            app.PlaneMenu.Text = 'Plane';
            app.FieldbusMenu = uimenu(app.InstallationMenu);
            app.FieldbusMenu.Text = 'Fieldbus';
            app.MoveMenu = uimenu(app.RobotHMIUIFigure);
            app.MoveMenu.Text = 'Move';
            app.IOMenu = uimenu(app.RobotHMIUIFigure);
            app.IOMenu.Text = 'I/O';
            app.LogMenu = uimenu(app.RobotHMIUIFigure);
            app.LogMenu.Text = 'Log';
            app.ReadingsMenu = uimenu(app.LogMenu);
            app.ReadingsMenu.Text = 'Readings';
            app.JointLoadMenu = uimenu(app.LogMenu);
            app.JointLoadMenu.Text = 'Joint Load';
            app.DateLogMenu = uimenu(app.LogMenu);
            app.DateLogMenu.Text = 'Date Log';
            app.PlotsMenu = uimenu(app.LogMenu);
            app.PlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotsMenuSelected, true);
            app.PlotsMenu.Text = 'Plots';
            app.SettingsMenu = uimenu(app.RobotHMIUIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.PreferencesMenu = uimenu(app.SettingsMenu);
            app.PreferencesMenu.Text = 'Preferences';
            app.PasswordMenu = uimenu(app.SettingsMenu);
            app.PasswordMenu.Text = 'Password';
            app.SystemMenu = uimenu(app.SettingsMenu);
            app.SystemMenu.Text = 'System';
            app.RobotRegistrationMenu = uimenu(app.SystemMenu);
            app.RobotRegistrationMenu.Text = 'Robot Registration';
            app.Joint1DegreeAngle = uieditfield(app.RobotHMIUIFigure, 'numeric');
            app.Joint1DegreeAngle.ValueDisplayFormat = '%.1f';
            app.Joint1DegreeAngle.ValueChangedFcn = createCallbackFcn(app, @Joint1DegreeAngleValueChanged, true);
            app.Joint2DegreeAngle = uieditfield(app.RobotHMIUIFigure, 'numeric');
            app.Joint2DegreeAngle.ValueDisplayFormat = '%.1f';
            app.Joint2DegreeAngle.ValueChangedFcn = createCallbackFcn(app, @Joint2DegreeAngleValueChanged, true);
            app.Joint5DegreeAngle = uieditfield(app.RobotHMIUIFigure, 'numeric');
            app.Joint5DegreeAngle.ValueDisplayFormat = '%.1f';
            app.Joint5DegreeAngle.ValueChangedFcn = createCallbackFcn(app, @Joint5DegreeAngleValueChanged, true);
            app.Joint4DegreeAngle = uieditfield(app.RobotHMIUIFigure, 'numeric');
            app.Joint4DegreeAngle.ValueDisplayFormat = '%.1f';
            app.Joint4DegreeAngle.ValueChangedFcn = createCallbackFcn(app, @Joint4DegreeAngleValueChanged, true);
            app.Joint3DegreeAngle = uieditfield(app.RobotHMIUIFigure, 'numeric');
            app.Joint3DegreeAngle.ValueDisplayFormat = '%.1f';
            app.Joint3DegreeAngle.ValueChangedFcn = createCallbackFcn(app, @Joint3DegreeAngleValueChanged, true);
            app.Joint1Label = uilabel(app.RobotHMIUIFigure);
            app.Joint1Label.Text = 'Joint 1';
            app.Joint2Label = uilabel(app.RobotHMIUIFigure);
            app.Joint2Label.Text = 'Joint 2';
            app.Joint3Label = uilabel(app.RobotHMIUIFigure);
            app.Joint3Label.Text = 'Joint 3';
            app.Joint4Label = uilabel(app.RobotHMIUIFigure);
            app.Joint4Label.Text = 'Joint 4';
            app.Joint5Label = uilabel(app.RobotHMIUIFigure);
            app.Joint5Label.Text = 'Joint 5';
            app.HomeButton = uibutton(app.RobotHMIUIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Icon = 'home.png';
            app.HomeButton.Text = 'Home';
            app.SliderJoint1 = uislider(app.RobotHMIUIFigure);
            app.SliderJoint1.Limits = [-420 420];
            app.SliderJoint1.ValueChangedFcn = createCallbackFcn(app, @SliderJoint1ValueChanged, true);
            app.SliderJoint1.ValueChangingFcn = createCallbackFcn(app, @SliderJoint1ValueChanging, true);
            app.SliderJoint2 = uislider(app.RobotHMIUIFigure);
            app.SliderJoint2.Limits = [-360 360];
            app.SliderJoint2.ValueChangedFcn = createCallbackFcn(app, @SliderJoint2ValueChanged, true);
            app.SliderJoint2.ValueChangingFcn = createCallbackFcn(app, @SliderJoint2ValueChanging, true);
            app.SliderJoint3 = uislider(app.RobotHMIUIFigure);
            app.SliderJoint3.Limits = [-360 360];
            app.SliderJoint3.ValueChangedFcn = createCallbackFcn(app, @SliderJoint3ValueChanged, true);
            app.SliderJoint3.ValueChangingFcn = createCallbackFcn(app, @SliderJoint3ValueChanging, true);
            app.SliderJoint4 = uislider(app.RobotHMIUIFigure);
            app.SliderJoint4.Limits = [-420 420];
            app.SliderJoint4.ValueChangedFcn = createCallbackFcn(app, @SliderJoint4ValueChanged, true);
            app.SliderJoint4.ValueChangingFcn = createCallbackFcn(app, @SliderJoint4ValueChanging, true);
            app.SliderJoint5 = uislider(app.RobotHMIUIFigure);
            app.SliderJoint5.Limits = [-360 360];
            app.SliderJoint5.ValueChangedFcn = createCallbackFcn(app, @SliderJoint5ValueChanged, true);
            app.SliderJoint5.ValueChangingFcn = createCallbackFcn(app, @SliderJoint5ValueChanging, true);
            app.T05Table = uitable(app.RobotHMIUIFigure);
            app.T05Table.ColumnName = {''};
            app.T05Table.RowName = {};
            app.RightArrowSlider1 = uibutton(app.RobotHMIUIFigure, 'push');
            app.RightArrowSlider1.ButtonPushedFcn = createCallbackFcn(app, @RightArrowSlider1ButtonPushed, true);
            app.RightArrowSlider1.Icon = 'right_arrow.png';
            app.RightArrowSlider1.Text = '';
            app.RightArrowSlider2 = uibutton(app.RobotHMIUIFigure, 'push');
            app.RightArrowSlider2.ButtonPushedFcn = createCallbackFcn(app, @RightArrowSlider2ButtonPushed, true);
            app.RightArrowSlider2.Icon = 'right_arrow.png';
            app.RightArrowSlider2.Text = '';
            app.RightArrowSlider3 = uibutton(app.RobotHMIUIFigure, 'push');
            app.RightArrowSlider3.ButtonPushedFcn = createCallbackFcn(app, @RightArrowSlider3ButtonPushed, true);
            app.RightArrowSlider3.Icon = 'right_arrow.png';
            app.RightArrowSlider3.Text = '';
            app.RightArrowSlider4 = uibutton(app.RobotHMIUIFigure, 'push');
            app.RightArrowSlider4.ButtonPushedFcn = createCallbackFcn(app, @RightArrowSlider4ButtonPushed, true);
            app.RightArrowSlider4.Icon = 'right_arrow.png';
            app.RightArrowSlider4.Text = '';
            app.RightArrowSlider5 = uibutton(app.RobotHMIUIFigure, 'push');
            app.RightArrowSlider5.ButtonPushedFcn = createCallbackFcn(app, @RightArrowSlider5ButtonPushed, true);
            app.RightArrowSlider5.Icon = 'right_arrow.png';
            app.RightArrowSlider5.Text = '';
            app.LeftArrowSlider1 = uibutton(app.RobotHMIUIFigure, 'push');
            app.LeftArrowSlider1.ButtonPushedFcn = createCallbackFcn(app, @LeftArrowSlider1ButtonPushed, true);
            app.LeftArrowSlider1.Icon = 'left_arrow.png';
            app.LeftArrowSlider1.Text = '';
            app.LeftArrowSlider2 = uibutton(app.RobotHMIUIFigure, 'push');
            app.LeftArrowSlider2.ButtonPushedFcn = createCallbackFcn(app, @LeftArrowSlider2ButtonPushed, true);
            app.LeftArrowSlider2.Icon = 'left_arrow.png';
            app.LeftArrowSlider2.Text = '';
            app.LeftArrowSlider3 = uibutton(app.RobotHMIUIFigure, 'push');
            app.LeftArrowSlider3.ButtonPushedFcn = createCallbackFcn(app, @LeftArrowSlider3ButtonPushed, true);
            app.LeftArrowSlider3.Icon = 'left_arrow.png';
            app.LeftArrowSlider3.Text = '';
            app.LeftArrowSlider4 = uibutton(app.RobotHMIUIFigure, 'push');
            app.LeftArrowSlider4.ButtonPushedFcn = createCallbackFcn(app, @LeftArrowSlider4ButtonPushed, true);
            app.LeftArrowSlider4.Icon = 'left_arrow.png';
            app.LeftArrowSlider4.Text = '';
            app.LeftArrowSlider5 = uibutton(app.RobotHMIUIFigure, 'push');
            app.LeftArrowSlider5.ButtonPushedFcn = createCallbackFcn(app, @LeftArrowSlider5ButtonPushed, true);
            app.LeftArrowSlider5.Icon = 'left_arrow.png';
            app.LeftArrowSlider5.Text = '';
            app.ControlButton = uibutton(app.RobotHMIUIFigure, 'push');
            app.ControlButton.ButtonPushedFcn = createCallbackFcn(app, @ControlButtonPushed, true);
            app.ControlButton.Text = 'Calculate Control';
            app.JointPositionLabel = uilabel(app.RobotHMIUIFigure);
            app.JointPositionLabel.Text = 'Joint Position';
            app.ToolPositionLabel = uilabel(app.RobotHMIUIFigure);
            app.ToolPositionLabel.Text = 'Tool Position';
            app.XLabel = uilabel(app.RobotHMIUIFigure);
            app.XLabel.Text = 'X';
            app.Label_6 = uilabel(app.RobotHMIUIFigure);
            app.Label_6.Text = 'α';
            app.mmLabel = uilabel(app.RobotHMIUIFigure);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.RobotHMIUIFigure);
            app.mmLabel_2.Text = 'mm';
            app.mmLabel_3 = uilabel(app.RobotHMIUIFigure);
            app.mmLabel_3.Text = 'mm';
            app.YLabel = uilabel(app.RobotHMIUIFigure);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.RobotHMIUIFigure);
            app.ZLabel.Text = 'Z';
            app.Label_7 = uilabel(app.RobotHMIUIFigure);
            app.Label_7.Text = 'β';
            app.TransformationMatrixT05Label = uilabel(app.RobotHMIUIFigure);
            app.TransformationMatrixT05Label.Text = 'Transformation Matrix (T05)';
            app.PlanDropDownLabel = uilabel(app.RobotHMIUIFigure);
            app.PlanDropDownLabel.Text = 'Plan';
            app.PlanDropDown = uidropdown(app.RobotHMIUIFigure);
            app.PlanDropDown.Items = {'Isometric', 'XY', 'XZ', 'YZ'};
            app.PlanDropDown.ValueChangedFcn = createCallbackFcn(app, @PlanDropDownValueChanged, true);
            app.PlanDropDown.Value = 'Isometric';
            app.Label = uilabel(app.RobotHMIUIFigure);
            app.Label.Text = '°';
            app.Label_2 = uilabel(app.RobotHMIUIFigure);
            app.Label_2.Text = '°';
            app.Label_3 = uilabel(app.RobotHMIUIFigure);
            app.Label_3.Text = '°';
            app.Label_4 = uilabel(app.RobotHMIUIFigure);
            app.Label_4.Text = '°';
            app.Label_5 = uilabel(app.RobotHMIUIFigure);
            app.Label_5.Text = '°';
            app.AnimationSwitchLabel = uilabel(app.RobotHMIUIFigure);
            app.AnimationSwitchLabel.Text = 'Animation';
            app.AnimationSwitch = uiswitch(app.RobotHMIUIFigure, 'slider');
            app.AnimationSwitch.Value = 'On';
            app.InverseKinematicButton = uibutton(app.RobotHMIUIFigure, 'push');
            app.InverseKinematicButton.ButtonPushedFcn = createCallbackFcn(app, @InverseKinematicButtonPushed, true);
            app.InverseKinematicButton.Text = 'Inverse Kinematic';
            app.positionZ = uieditfield(app.RobotHMIUIFigure, 'numeric');
            app.positionZ.ValueDisplayFormat = '%.1f';
            app.positionY = uieditfield(app.RobotHMIUIFigure, 'numeric');
            app.positionY.ValueDisplayFormat = '%.1f';
            app.positionX = uieditfield(app.RobotHMIUIFigure, 'numeric');
            app.positionX.ValueDisplayFormat = '%.1f';
            app.alpha = uieditfield(app.RobotHMIUIFigure, 'numeric');
            app.alpha.ValueDisplayFormat = '%.1f';
            app.beta = uieditfield(app.RobotHMIUIFigure, 'numeric');
            app.beta.ValueDisplayFormat = '%.1f';
            app.Label_8 = uilabel(app.RobotHMIUIFigure);
            app.Label_8.Text = '°';
            app.Label_9 = uilabel(app.RobotHMIUIFigure);
            app.Label_9.Text = '°';
            app.NotPossibleLabel = uilabel(app.RobotHMIUIFigure);
            app.NotPossibleLabel.Visible = 'off';
            app.NotPossibleLabel.Text = 'Not possible choose other values!';
            app.UIAxes = uiaxes(app.RobotHMIUIFigure);
            % Show the figure after all components are created
            app.RobotHMIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IHMRoboDidatico_V0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RobotHMIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RobotHMIUIFigure)
        end
    end
end