classdef logPlotWindowApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        JointsAngularPositionCheckBox  matlab.ui.control.CheckBox
        JointsAngularVelocityCheckBox  matlab.ui.control.CheckBox
        JointsAngularAccelerationCheckBox  matlab.ui.control.CheckBox
        PlotButton                     matlab.ui.control.Button
        AngularPositionErrorCheckBox   matlab.ui.control.CheckBox
        ControlEffortCheckBox          matlab.ui.control.CheckBox
        ChoosethegraphicstoplotLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp; % Main app object
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, th_realizado, th_desejado, dth_realizado, dth_desejado, ddth_realizado, ddth_desejado, esforco_controle, theta_erro, tout)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % Delete the checkbox window
            if app.JointsAngularPositionCheckBox == 1
                plotJointsPosition(app.CallingApp.th_realizado);
            end
            
            plotGraphics(app.CallingApp.th_realizado, app.CallingApp.th_desejado, ...
                         app.CallingApp.dth_realizado, app.CallingApp.dth_desejado, ...
                         app.CallingApp.ddth_realizado, app.CallingApp.ddth_desejado, ...
                         app.CallingApp.esforco_controle, ...
                         app.CallingApp.theta_erro, app.CallingApp.tout, ...
                         app.JointsAngularPositionCheckBox.Value, ...
                         app.JointsAngularVelocityCheckBox.Value, ...
                         app.JointsAngularAccelerationCheckBox.Value, ...
                         app.ControlEffortCheckBox.Value, ...
                         app.AngularPositionErrorCheckBox.Value);
            
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.JointsAngularPositionCheckBox = uicheckbox(app.UIFigure);
            app.JointsAngularPositionCheckBox.Text = 'Joints Angular Position';
            app.JointsAngularVelocityCheckBox = uicheckbox(app.UIFigure);
            app.JointsAngularVelocityCheckBox.Text = 'Joints Angular Velocity';
            app.JointsAngularAccelerationCheckBox = uicheckbox(app.UIFigure);
            app.JointsAngularAccelerationCheckBox.Text = 'Joints Angular Acceleration';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.AngularPositionErrorCheckBox = uicheckbox(app.UIFigure);
            app.AngularPositionErrorCheckBox.Text = 'Angular Position Error';
            app.ControlEffortCheckBox = uicheckbox(app.UIFigure);
            app.ControlEffortCheckBox.Text = 'Control Effort';
            app.ChoosethegraphicstoplotLabel = uilabel(app.UIFigure);
            app.ChoosethegraphicstoplotLabel.Text = 'Choose the graphics to plot:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = logPlotWindowApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end